classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Slider2Label       matlab.ui.control.Label
        SliderLabel        matlab.ui.control.Label
        BeepButton         matlab.ui.control.Button
        WhiteNoiseButton   matlab.ui.control.Button
        PlayAudioButton    matlab.ui.control.Button
        AddNoiseButton     matlab.ui.control.Button
        RecordAudioButton  matlab.ui.control.Button
        VolumeSlider       matlab.ui.control.Slider
        SpeedSlider        matlab.ui.control.Slider
        EchoButton         matlab.ui.control.Button
        RemoveNoiseButton  matlab.ui.control.Button
        SubtractButton     matlab.ui.control.Button
        MultiplyButton     matlab.ui.control.Button
        PlotButton         matlab.ui.control.Button
        AudioAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BeepButton
        function BeepButtonPushed(app, event)
            fs = 44100; % Sampling frequency
            t = 0:1/fs:1; % Time vector for 1 second
            beepSound = sin(2 * pi * 440 * t); % Beep sound at 440 Hz
            sound(beepSound, fs);
        end

        % Button pushed function: WhiteNoiseButton
        function WhiteNoiseButtonPushed(app, event)
             fs = 44100; % Sampling frequency
            whiteNoise = randn(1, fs); % White noise signal
            sound(whiteNoise, fs);
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
          audioPath = 'mi.mp3';  % Replace with your file path
    [audio, fs] = audioread(audioPath);
    
    % Update audioData and fs properties
    app.audioData = audio;
    app.fs = fs;
    
    % Play the audio using sound
    sound(audio, fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AudioAxes = uiaxes(app.UIFigure);
            title(app.AudioAxes, 'Title')
            xlabel(app.AudioAxes, 'X')
            ylabel(app.AudioAxes, 'Y')
            zlabel(app.AudioAxes, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.Text = 'PlotButton ';
            app.MultiplyButton = uibutton(app.UIFigure, 'push');
            app.MultiplyButton.Text = 'MultiplyButton ';
            app.SubtractButton = uibutton(app.UIFigure, 'push');
            app.SubtractButton.Text = 'SubtractButton';
            app.RemoveNoiseButton = uibutton(app.UIFigure, 'push');
            app.RemoveNoiseButton.Text = 'RemoveNoiseButton';
            app.EchoButton = uibutton(app.UIFigure, 'push');
            app.EchoButton.Text = 'EchoButton';
            app.SpeedSlider = uislider(app.UIFigure);
            app.VolumeSlider = uislider(app.UIFigure);
            app.RecordAudioButton = uibutton(app.UIFigure, 'push');
            app.RecordAudioButton.Text = 'RecordAudioButton';
            app.AddNoiseButton = uibutton(app.UIFigure, 'push');
            app.AddNoiseButton.Text = 'AddNoiseButton';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'PlayAudioButton';
            app.WhiteNoiseButton = uibutton(app.UIFigure, 'push');
            app.WhiteNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @WhiteNoiseButtonPushed, true);
            app.WhiteNoiseButton.Text = 'WhiteNoiseButton';
            app.BeepButton = uibutton(app.UIFigure, 'push');
            app.BeepButton.ButtonPushedFcn = createCallbackFcn(app, @BeepButtonPushed, true);
            app.BeepButton.Text = 'BeepButton';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider2Label = uilabel(app.UIFigure);
            app.Slider2Label.Text = 'Slider2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end